classdef about_box_window2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        about_box_window  matlab.ui.Figure
        axes_about_image  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function about_box_window_OpeningFcn(app, varargin)
            % --- about_box_window2が表示される前に実行されます。----------------------------
            
            % Ensure that the app appears on screen when run
            movegui(app.about_box_window, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % この関数は、GUIウィンドウが表示される前に実行されます。
            % ウィンドウの初期設定を行い、表示位置や内容を設定します。
            
            % hObject   図のハンドル
            % eventdata 将来のバージョンの MATLAB で定義される予定のイベントデータ
            % handles   ハンドルとユーザーデータを持つ構造体（GUIDATA参照）
            % varargin  about_box_window へのコマンドライン引数 (VARARGIN 参照)
            
            % about_box_window2のデフォルトのコマンドライン出力を設定
            handles.output = hObject;
            
            % ハンドル構造の更新
            guidata(hObject, handles);
            
            %---- ウィンドウの表示位置をスクリーンの右上に設定する
            global SCR_SIZE % スクリーンサイズと幅 (1x4, [x y width height])
            j = get(hObject,'Position');
            wind_width = j(3);
            wind_height = j(4);
            xpos = SCR_SIZE.SCRW_X;
            ypos = (SCR_SIZE.SCRS(1,4) - SCR_SIZE.SCRW_Y) - wind_height;
            set(hObject,'Position',[xpos ypos wind_width wind_height]);
            
            %---- イメージの設定
            cd slides2
            str = 'About_image2.jpg';
            [x,imap] = imread(str);
            cd ..
            b = image(x);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.about_box_window = uifigure('Visible', 'off');
            colormap(app.about_box_window, 'parula');
            app.about_box_window.Tag = 'about_box_window';
            app.axes_about_image = uiaxes(app.about_box_window);
            app.axes_about_image.Tag = 'axes_about_image';
            % Show the figure after all components are created
            app.about_box_window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = about_box_window2_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.about_box_window)

                % Execute the startup function
                runStartupFcn(app, @(app)about_box_window_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.about_box_window)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.about_box_window)
        end
    end
end