classdef input_window_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        input_window        matlab.ui.Figure
        uipanel2            matlab.ui.container.Panel
        text61_2            matlab.ui.control.Label
        text78              matlab.ui.control.Label
        text77              matlab.ui.control.Label
        text11              matlab.ui.control.Label
        text8               matlab.ui.control.Label
        text64              matlab.ui.control.Label
        text63              matlab.ui.control.Label
        text62              matlab.ui.control.Label
        text61              matlab.ui.control.Label
        text59              matlab.ui.control.Label
        text58              matlab.ui.control.Label
        text57              matlab.ui.control.Label
        text54              matlab.ui.control.Label
        text53              matlab.ui.control.Label
        text22              matlab.ui.control.Label
        text21              matlab.ui.control.Label
        text20              matlab.ui.control.Label
        text17              matlab.ui.control.Label
        text14              matlab.ui.control.Label
        text10              matlab.ui.control.Label
        text9               matlab.ui.control.Label
        edit_s3grad         matlab.ui.control.EditField
        edit_s3in           matlab.ui.control.EditField
        edit_s3dip          matlab.ui.control.EditField
        edit_s3or           matlab.ui.control.EditField
        edit_s2grad         matlab.ui.control.EditField
        edit_s2in           matlab.ui.control.EditField
        edit_s2dip          matlab.ui.control.EditField
        edit_s2or           matlab.ui.control.EditField
        edit_s1grad         matlab.ui.control.EditField
        edit_s1in           matlab.ui.control.EditField
        edit_s1dip          matlab.ui.control.EditField
        edit_s1or           matlab.ui.control.EditField
        uipanel1            matlab.ui.container.Panel
        text52              matlab.ui.control.Label
        text4               matlab.ui.control.Label
        text3               matlab.ui.control.Label
        text2               matlab.ui.control.Label
        edit_young          matlab.ui.control.EditField
        edit_poisson        matlab.ui.control.EditField
        uipanel5            matlab.ui.container.Panel
        edit_bottom         matlab.ui.control.EditField
        edit_top            matlab.ui.control.EditField
        edit_dip            matlab.ui.control.EditField
        edit_dips           matlab.ui.control.EditField
        edit_laterals       matlab.ui.control.EditField
        edit_input_kode     matlab.ui.control.EditField
        edit_fyfinish       matlab.ui.control.EditField
        edit_fxfinish       matlab.ui.control.EditField
        edit_fystart        matlab.ui.control.EditField
        edit_fxstart        matlab.ui.control.EditField
        edit_elem           matlab.ui.control.EditField
        edit_totalnf        matlab.ui.control.EditField
        text67              matlab.ui.control.Label
        text51              matlab.ui.control.Label
        text50              matlab.ui.control.Label
        text49              matlab.ui.control.Label
        text48              matlab.ui.control.Label
        text_input_dipslip  matlab.ui.control.Label
        text_input_latslip  matlab.ui.control.Label
        text45              matlab.ui.control.Label
        text43              matlab.ui.control.Label
        text42              matlab.ui.control.Label
        text41              matlab.ui.control.Label
        text40              matlab.ui.control.Label
        text76              matlab.ui.control.Label
        text75              matlab.ui.control.Label
        text74              matlab.ui.control.Label
        text_slip2_unit     matlab.ui.control.Label
        text_slip1_unit     matlab.ui.control.Label
        text71              matlab.ui.control.Label
        text70              matlab.ui.control.Label
        text69              matlab.ui.control.Label
        text68              matlab.ui.control.Label
        pushbutton_ok       matlab.ui.control.Button
        pushbutton_saveas   matlab.ui.control.Button
        uipanel3            matlab.ui.container.Panel
        text39              matlab.ui.control.Label
        text38              matlab.ui.control.Label
        text37              matlab.ui.control.Label
        text36              matlab.ui.control.Label
        text35              matlab.ui.control.Label
        text34              matlab.ui.control.Label
        text33              matlab.ui.control.Label
        text32              matlab.ui.control.Label
        text31              matlab.ui.control.Label
        text30              matlab.ui.control.Label
        text29              matlab.ui.control.Label
        text28              matlab.ui.control.Label
        text27              matlab.ui.control.Label
        text26              matlab.ui.control.Label
        edit_yinc           matlab.ui.control.EditField
        edit_yfinish        matlab.ui.control.EditField
        edit_ystart         matlab.ui.control.EditField
        edit_xinc           matlab.ui.control.EditField
        edit_xfinish        matlab.ui.control.EditField
        edit_xstart         matlab.ui.control.EditField
        text7               matlab.ui.control.Label
        text6               matlab.ui.control.Label
        text5               matlab.ui.control.Label
        edit_fric           matlab.ui.control.EditField
        edit_calcdepth      matlab.ui.control.EditField
        edit_hline2         matlab.ui.control.EditField
        edit_hline1         matlab.ui.control.EditField
        text1               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function input_window_OpeningFcn(app, varargin)
            %"""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""
            %     OpeningFcn
            %"""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""""
            
            % Ensure that the app appears on screen when run
            movegui(app.input_window, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            global SCR_SIZE  % screen size (1x4, [x y width height]) & width
            global INPUT_VARS
            
            if isempty(INPUT_VARS.INUM)
                INPUT_VARS.INUM = 1;
            end
            h = get(hObject,'Position');
            wind_width = h(3);
            wind_height = h(4);
            dummy = findobj('Tag','main_menu_window2');
            if isempty(dummy)~=1
                h = get(dummy,'Position');
            end
            shift = 30;
            xpos = h(1,1) + shift;
            ypos = (SCR_SIZE.SCRS(1,4) - SCR_SIZE.SCRW_Y) - wind_height + shift;
            set(hObject,'Position',[xpos ypos wind_width wind_height]);
            % Choose default command line output for input_window
            handles.output = hObject;
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit_bottom
        function edit_bottom_Callback(app, event)
            %-------------------------------------------------------------------------
            %   FAULT (BOTTOM)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.ELEMENT(INPUT_VARS.INUM,9) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,9),'%6.2f'));
        end

        % Value changed function: edit_calcdepth
        function edit_calcdepth_Callback(app, event)
            %-------------------------------------------------------------------------
            %   CALC. DEPTH (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.CALC_DEPTH = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.CALC_DEPTH,'%6.2f'));
        end

        % Value changed function: edit_dip
        function edit_dip_Callback(app, event)
            %-------------------------------------------------------------------------
            %   FAULT (DIP)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.ELEMENT(INPUT_VARS.INUM,7) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,7),'%5.1f'));
        end

        % Value changed function: edit_dips
        function edit_dips_Callback(app, event)
            %-------------------------------------------------------------------------
            %   FAULT (DIP SLIP)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.ELEMENT(INPUT_VARS.INUM,6) = str2num(get(hObject,'String'));
            if INPUT_VARS.KODE(INPUT_VARS.INUM) == 400 || INPUT_VARS.KODE(INPUT_VARS.INUM) == 500
                set(hObject,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,6),'%4.1e'));
            else
                if INPUT_VARS.IRAKE == 1
                    x = sqrt(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,5)^2+INPUT_VARS.ELEMENT(INPUT_VARS.INUM,6)^2);
                    set(hObject,'String',num2str(x,'%7.3f'));
                else
                    set(hObject,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,6),'%7.3f'));
                end
            end
        end

        % Value changed function: edit_elem
        function edit_elem_Callback(app, event)
            %-------------------------------------------------------------------------
            %   FAULT (i-th fault element)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.INUM = str2num(get(hObject,'String'));
            if INPUT_VARS.INUM > INPUT_VARS.NUM
                warndlg('This must not over the total number','Warning!');
            end
            set(handles.edit_fxstart,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,1),'%9.2f'))
            set(handles.edit_fystart,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,2),'%9.2f'))
            set(handles.edit_fxfinish,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,3),'%9.2f'))
            set(handles.edit_fyfinish,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,4),'%9.2f'))
            set(handles.edit_laterals,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,5),'%7.3f'))
            set(handles.edit_dips,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,6),'%7.3f'))
            set(handles.edit_dip,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,7),'%5.1f'))
            set(handles.edit_top,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,8),'%6.2f'))
            set(handles.edit_bottom,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,9),'%6.2f'))
        end

        % Value changed function: edit_fric
        function edit_fric_Callback(app, event)
            %-------------------------------------------------------------------------
            %   FRICTION (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.FRIC = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.FRIC,'%3.1f'));
        end

        % Value changed function: edit_fxfinish
        function edit_fxfinish_Callback(app, event)
            %-------------------------------------------------------------------------
            %   FAULT (X-FINISH)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.ELEMENT(INPUT_VARS.INUM,3) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,3),'%9.2f'));
        end

        % Value changed function: edit_fxstart
        function edit_fxstart_Callback(app, event)
            %-------------------------------------------------------------------------
            %   FAULT (X-START)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.ELEMENT(INPUT_VARS.INUM,1) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,1),'%9.2f'));
        end

        % Value changed function: edit_fyfinish
        function edit_fyfinish_Callback(app, event)
            %-------------------------------------------------------------------------
            %   FAULT (Y-FINISH)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.ELEMENT(INPUT_VARS.INUM,4) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,4),'%9.2f'));
        end

        % Value changed function: edit_fystart
        function edit_fystart_Callback(app, event)
            %-------------------------------------------------------------------------
            %   FAULT (Y-START)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.ELEMENT(INPUT_VARS.INUM,2) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,2),'%9.2f'));
        end

        % Value changed function: edit_hline1
        function edit_hline1_Callback(app, event)
            %-------------------------------------------------------------------------
            %   HEADER LINE 1 (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.HEAD(1,:) = get(hObject,'String');
            set (hObject,'String',INPUT_VARS.HEAD(1,:));
        end

        % Value changed function: edit_hline2
        function edit_hline2_Callback(app, event)
            %-------------------------------------------------------------------------
            %   HEADER LINE 2 (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.HEAD(2,:) = get(hObject,'String');
            set (hObject,'String',INPUT_VARS.HEAD(2,:));
        end

        % Value changed function: edit_input_kode
        function edit_input_kode_Callback(app, event)
            %-------------------------------------------------------------------------
            %   KODE  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            x = str2num(get(hObject,'String'));
            if x ~= 100 && x ~= 200 && x ~= 300 && x ~= 400 && x ~= 500
                h = warndlg('Kode should be 100 or 200 or 300 or 400 or 500.','!! Warning !!');
                waitfor(h);
            else
                INPUT_VARS.KODE(INPUT_VARS.INUM) = x;
            end
            set(hObject,'String',num2str(INPUT_VARS.KODE(INPUT_VARS.INUM),'%3i'));
            if INPUT_VARS.KODE(INPUT_VARS.INUM) == 100
                if INPUT_VARS.IRAKE == 1   % rake & netslip type
                    set(findobj('Tag','text_input_latslip'),'String','rake');
                    set(findobj('Tag','text_slip1_unit'),'String','(�)');
                    set(findobj('Tag','text_input_dipslip'),'String','net slip');
                    set(findobj('Tag','text_slip2_unit'),'String','(m)');
                else            % lateral slip and dip slip type
                    set(findobj('Tag','text_input_latslip'),'String','rt.lat slip');
                    set(findobj('Tag','text_slip1_unit'),'String','(m)');
                    set(findobj('Tag','text_input_dipslip'),'String','dip slip');
                    set(findobj('Tag','text_slip2_unit'),'String','(m)');
                end
            elseif INPUT_VARS.KODE(INPUT_VARS.INUM) == 200
                set(findobj('Tag','text_input_latslip'),'String','rt.lat slip');
                set(findobj('Tag','text_slip1_unit'),'String','(m)');
                set(findobj('Tag','text_input_dipslip'),'String','tensile slip');
             	set(findobj('Tag','text_slip2_unit'),'String','(m)');
            elseif INPUT_VARS.KODE(INPUT_VARS.INUM) == 300
                set(findobj('Tag','text_input_latslip'),'String','tensile slip');
            	set(findobj('Tag','text_slip1_unit'),'String','(m)');
                set(findobj('Tag','text_input_dipslip'),'String','dip slip');
            	set(findobj('Tag','text_slip2_unit'),'String','(m)');
            elseif INPUT_VARS.KODE(INPUT_VARS.INUM) == 400
                set(findobj('Tag','text_input_latslip'),'String','rt.lat pot.');
            	set(findobj('Tag','text_slip1_unit'),'String','(m^3)');
                set(findobj('Tag','text_input_dipslip'),'String','dip pot.');
            	set(findobj('Tag','text_slip2_unit'),'String','(m^3)');
            elseif INPUT_VARS.KODE(INPUT_VARS.INUM) == 500
                set(findobj('Tag','text_input_latslip'),'String','tensile pot.');
            	set(findobj('Tag','text_slip1_unit'),'String','(m^3)');
                set(findobj('Tag','text_input_dipslip'),'String','inflate pot.');
            	set(findobj('Tag','text_slip2_unit'),'String','(m)^3');
            end
        end

        % Value changed function: edit_laterals
        function edit_laterals_Callback(app, event)
            %-------------------------------------------------------------------------
            %   FAULT (LATERAL SLIP)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            global IND_RAKE
            INPUT_VARS.ELEMENT(INPUT_VARS.INUM,5) = str2num(get(hObject,'String'));
            if INPUT_VARS.KODE(INPUT_VARS.INUM) == 400 || INPUT_VARS.KODE(INPUT_VARS.INUM) == 500
                set(hObject,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,5),'%4.1e'));
            else
                if INPUT_VARS.IRAKE == 1
                    set(hObject,'String',num2str(IND_RAKE(INPUT_VARS.INUM),'%5.1f'));
                else
                    set(hObject,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,5),'%7.3f'));
                end
            end
        end

        % Value changed function: edit_poisson
        function edit_poisson_Callback(app, event)
            %-------------------------------------------------------------------------
            %   POISSON RATIO (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.POIS = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(POIS,'%6.2f'));
        end

        % Value changed function: edit_s1dip
        function edit_s1dip_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SIGMA 1 (DIP)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.R_STRESS(1,2) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.R_STRESS(1,2),'%7.1f'));
        end

        % Value changed function: edit_s1grad
        function edit_s1grad_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SIGMA 1 (GRADIENT)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.R_STRESS(1,4) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.R_STRESS(1,4),'%7.1f'));
        end

        % Value changed function: edit_s1in
        function edit_s1in_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SIGMA 1 (INITIAL STRESS)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.R_STRESS(1,3) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.R_STRESS(1,3),'%7.1f'));
        end

        % Value changed function: edit_s1or
        function edit_s1or_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SIGMA 1 (ORIENTATION)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.R_STRESS(1,1) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.R_STRESS(1,1),'%7.1f'));
        end

        % Value changed function: edit_s2dip
        function edit_s2dip_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SIGMA 2 (DIP)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.R_STRESS(2,2) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.R_STRESS(2,2),'%7.1f'));
        end

        % Value changed function: edit_s2grad
        function edit_s2grad_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SIGMA 2 (GRADIENT)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.R_STRESS(2,4) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.R_STRESS(2,4),'%7.1f'));
        end

        % Value changed function: edit_s2in
        function edit_s2in_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SIGMA 2 (INITIAL STRESS)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.R_STRESS(2,3) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.R_STRESS(2,3),'%7.1f'));
        end

        % Value changed function: edit_s2or
        function edit_s2or_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SIGMA 2 (ORIENTATION)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.R_STRESS(2,1) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.R_STRESS(2,1),'%7.1f'));
        end

        % Value changed function: edit_s3dip
        function edit_s3dip_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SIGMA 3 (DIP)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.R_STRESS(3,2) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.R_STRESS(3,2),'%7.1f'));
        end

        % Value changed function: edit_s3grad
        function edit_s3grad_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SIGMA 3 (GRADIENT)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.R_STRESS(3,4) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.R_STRESS(3,4),'%7.1f'));
        end

        % Value changed function: edit_s3in
        function edit_s3in_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SIGMA 3 (INITIAL STRESS)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.R_STRESS(3,3) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.R_STRESS(3,3),'%7.1f'));
        end

        % Value changed function: edit_s3or
        function edit_s3or_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SIGMA 3 (ORIENTATION)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.R_STRESS(3,1) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.R_STRESS(3,1),'%7.1f'));
        end

        % Value changed function: edit_top
        function edit_top_Callback(app, event)
            %-------------------------------------------------------------------------
            %   FAULT (TOP)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.ELEMENT(INPUT_VARS.INUM,8) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.ELEMENT(INPUT_VARS.INUM,8),'%6.2f'));
        end

        % Value changed function: edit_totalnf
        function edit_totalnf_Callback(app, event)
            %-------------------------------------------------------------------------
            %   FAULT (TOTAL NUMBER OF FAULTS)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.NUM = str2num(get(hObject,'String'));
            INPUT_VARS.ELEMENT(INPUT_VARS.NUM,:);
            set(hObject,'String',num2str(INPUT_VARS.NUM));
        end

        % Value changed function: edit_xfinish
        function edit_xfinish_Callback(app, event)
            %-------------------------------------------------------------------------
            %   GRID (X-FINISH)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.GRID(3,1) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.GRID(3,1),'%7.2f'));
        end

        % Value changed function: edit_xinc
        function edit_xinc_Callback(app, event)
            %-------------------------------------------------------------------------
            %   GRID (X-INCREMENT)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.GRID(5,1) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.GRID(5,1),'%7.2f'));
        end

        % Value changed function: edit_xstart
        function edit_xstart_Callback(app, event)
            %=========================================================================
            %-------------------------------------------------------------------------
            %   GRID (X-START)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.GRID(1,1) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.GRID(1,1),'%7.2f'));
        end

        % Value changed function: edit_yfinish
        function edit_yfinish_Callback(app, event)
            %-------------------------------------------------------------------------
            %   GRID (Y-FINISH)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.GRID(4,1) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.GRID(4,1),'%7.2f'));
        end

        % Value changed function: edit_yinc
        function edit_yinc_Callback(app, event)
            %-------------------------------------------------------------------------
            %   GRID (Y-INCREMENT)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.GRID(6,1) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.GRID(6,1),'%7.2f'));
        end

        % Value changed function: edit_young
        function edit_young_Callback(app, event)
            %-------------------------------------------------------------------------
            %   YOUNG MODULUS (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.YOUNG = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.YOUNG,'%8.1f'));
        end

        % Value changed function: edit_ystart
        function edit_ystart_Callback(app, event)
            %-------------------------------------------------------------------------
            %   GRID (Y-START)  (textfield)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global INPUT_VARS
            INPUT_VARS.GRID(2,1) = str2num(get(hObject,'String'));
            set(hObject,'String',num2str(INPUT_VARS.GRID(2,1),'%7.2f'));
        end

        % Button pushed function: pushbutton_ok
        function pushbutton_ok_Callback(app, event)
            %-------------------------------------------------------------------------
            %   OK  (pushbutton)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            h = figure(gcf);
            delete(h);
            % to calculate and save numbers for basic info
            calc_element;
            %
            %----- DRAW GRID IN MAIN WINDOW ------ (copied from ...)
            global CALC_CONTROL
            global OVERLAY_VARS
            subfig_clear;
            CALC_CONTROL.FUNC_SWITCH = 1;
            grid_drawing2;
            fault_overlay;
            if isempty(OVERLAY_VARS.COAST_DATA)~=1 | isempty(OVERLAY_VARS.EQ_DATA)~=1 | isempty(OVERLAY_VARS.AFAULT_DATA)~=1
                hold on;
                overlay_drawing;
            end
            CALC_CONTROL.FUNC_SWITCH = 0; %reset
            flag = check_lonlat_info2;
            if flag == 1
                %----------------------------------------------------------
                % function all_overlay_enable_on
                set(findobj('Tag','menu_coastlines'),'Enable','On');
                set(findobj('Tag','menu_activefaults'),'Enable','On');
                set(findobj('Tag','menu_earthquakes'),'Enable','On');
                set(findobj('Tag','menu_gps'),'Enable','On');
                set(findobj('Tag','menu_annotations'),'Enable','On');
                set(findobj('Tag','menu_clear_overlay'),'Enable','On');
                set(findobj('Tag','menu_trace_put_faults'),'Enable','On');
            end
        end

        % Button pushed function: pushbutton_saveas
        function pushbutton_saveas_Callback(app, event)
            %-------------------------------------------------------------------------
            %   SAVE AS...  (pushbutton)
            %-------------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global SYSTEM_VARS
                if isempty(SYSTEM_VARS.PREF)==1
                    % make default values & then save them.
                    SYSTEM_VARS.PREF = [1.0 0.0 0.0 1.2;...
                                        0.0 0.0 0.0 1.0;...
                                        0.7 0.7 0.0 0.2;...
                                        0.0 0.0 0.0 1.2;...
                                        1.0 0.5 0.0 3.0;...
                                        0.2 0.2 0.2 1.0;...
                                        2.0 0.0 0.0 0.0;...
                                        1.0 0.0 0.0 0.0];
                end
                [filename,pathname] = uiputfile('*.inp',...
                    ' Save Input File As');
                if isequal(filename,0) | isequal(pathname,0)
                    disp('User selected Cancel')
                else
                    disp(['User saved as ', fullfile(pathname,filename)])
                end
                cd(pathname);
                input_save_ascii;
                cd(SYSTEM_VARS.HOME_DIR);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.input_window = uifigure('Visible', 'off');
            colormap(app.input_window, 'parula');
            app.input_window.Name = 'All input parameters';
            app.input_window.Tag = 'input_window';
            app.text1 = uilabel(app.input_window);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Comment lines';
            app.edit_hline1 = uieditfield(app.input_window, 'text');
            app.edit_hline1.ValueChangedFcn = createCallbackFcn(app, @edit_hline1_Callback, true);
            app.edit_hline1.Tag = 'edit_hline1';
            app.edit_hline1.Value = 'This is a test file for the Coulomb 4.0.0';
            app.edit_hline2 = uieditfield(app.input_window, 'text');
            app.edit_hline2.ValueChangedFcn = createCallbackFcn(app, @edit_hline2_Callback, true);
            app.edit_hline2.Tag = 'edit_hline2';
            app.edit_hline2.Value = 'This file is prepared to check mainly thrust faulting calculation';
            app.edit_calcdepth = uieditfield(app.input_window, 'text');
            app.edit_calcdepth.ValueChangedFcn = createCallbackFcn(app, @edit_calcdepth_Callback, true);
            app.edit_calcdepth.Tag = 'edit_calcdepth';
            app.edit_fric = uieditfield(app.input_window, 'text');
            app.edit_fric.ValueChangedFcn = createCallbackFcn(app, @edit_fric_Callback, true);
            app.edit_fric.Tag = 'edit_fric';
            app.text5 = uilabel(app.input_window);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Calculation depth';
            app.text6 = uilabel(app.input_window);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Coeff. friction';
            app.text7 = uilabel(app.input_window);
            app.text7.Tag = 'text7';
            app.text7.Text = 'km';
            app.uipanel3 = uipanel(app.input_window);
            app.uipanel3.Title = '  Study area (grid)  ';
            app.uipanel3.Tag = 'uipanel3';
            app.edit_xstart = uieditfield(app.uipanel3, 'text');
            app.edit_xstart.ValueChangedFcn = createCallbackFcn(app, @edit_xstart_Callback, true);
            app.edit_xstart.Tag = 'edit_xstart';
            app.edit_xstart.Value = '-50.00';
            app.edit_xfinish = uieditfield(app.uipanel3, 'text');
            app.edit_xfinish.ValueChangedFcn = createCallbackFcn(app, @edit_xfinish_Callback, true);
            app.edit_xfinish.Tag = 'edit_xfinish';
            app.edit_xfinish.Value = '50.10';
            app.edit_xinc = uieditfield(app.uipanel3, 'text');
            app.edit_xinc.ValueChangedFcn = createCallbackFcn(app, @edit_xinc_Callback, true);
            app.edit_xinc.Tag = 'edit_xinc';
            app.edit_xinc.Value = '2.00';
            app.edit_ystart = uieditfield(app.uipanel3, 'text');
            app.edit_ystart.ValueChangedFcn = createCallbackFcn(app, @edit_ystart_Callback, true);
            app.edit_ystart.Tag = 'edit_ystart';
            app.edit_ystart.Value = '-50.00';
            app.edit_yfinish = uieditfield(app.uipanel3, 'text');
            app.edit_yfinish.ValueChangedFcn = createCallbackFcn(app, @edit_yfinish_Callback, true);
            app.edit_yfinish.Tag = 'edit_yfinish';
            app.edit_yfinish.Value = '50.10';
            app.edit_yinc = uieditfield(app.uipanel3, 'text');
            app.edit_yinc.ValueChangedFcn = createCallbackFcn(app, @edit_yinc_Callback, true);
            app.edit_yinc.Tag = 'edit_yinc';
            app.edit_yinc.Value = '2.00';
            app.text26 = uilabel(app.uipanel3);
            app.text26.Tag = 'text26';
            app.text26.Text = 'X axis';
            app.text27 = uilabel(app.uipanel3);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Y axis';
            app.text28 = uilabel(app.uipanel3);
            app.text28.Tag = 'text28';
            app.text28.Text = 'start';
            app.text29 = uilabel(app.uipanel3);
            app.text29.Tag = 'text29';
            app.text29.Text = 'start';
            app.text30 = uilabel(app.uipanel3);
            app.text30.Tag = 'text30';
            app.text30.Text = 'finish';
            app.text31 = uilabel(app.uipanel3);
            app.text31.Tag = 'text31';
            app.text31.Text = 'finish';
            app.text32 = uilabel(app.uipanel3);
            app.text32.Tag = 'text32';
            app.text32.Text = 'increment';
            app.text33 = uilabel(app.uipanel3);
            app.text33.Tag = 'text33';
            app.text33.Text = 'increment';
            app.text34 = uilabel(app.uipanel3);
            app.text34.Tag = 'text34';
            app.text34.Text = 'km';
            app.text35 = uilabel(app.uipanel3);
            app.text35.Tag = 'text35';
            app.text35.Text = 'km';
            app.text36 = uilabel(app.uipanel3);
            app.text36.Tag = 'text36';
            app.text36.Text = 'km';
            app.text37 = uilabel(app.uipanel3);
            app.text37.Tag = 'text37';
            app.text37.Text = 'km';
            app.text38 = uilabel(app.uipanel3);
            app.text38.Tag = 'text38';
            app.text38.Text = 'km';
            app.text39 = uilabel(app.uipanel3);
            app.text39.Tag = 'text39';
            app.text39.Text = 'km';
            app.pushbutton_saveas = uibutton(app.input_window, 'push');
            app.pushbutton_saveas.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_saveas_Callback, true);
            app.pushbutton_saveas.Tag = 'pushbutton_saveas';
            app.pushbutton_saveas.Text = 'Save As';
            app.pushbutton_ok = uibutton(app.input_window, 'push');
            app.pushbutton_ok.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_ok_Callback, true);
            app.pushbutton_ok.Tag = 'pushbutton_ok';
            app.pushbutton_ok.Text = 'OK';
            app.uipanel5 = uipanel(app.input_window);
            app.uipanel5.Title = '  Fault Data  ';
            app.uipanel5.Tag = 'uipanel5';
            app.text68 = uilabel(app.uipanel5);
            app.text68.Tag = 'text68';
            app.text68.Text = '(km)';
            app.text69 = uilabel(app.uipanel5);
            app.text69.Tag = 'text69';
            app.text69.Text = '(km)';
            app.text70 = uilabel(app.uipanel5);
            app.text70.Tag = 'text70';
            app.text70.Text = '(km)';
            app.text71 = uilabel(app.uipanel5);
            app.text71.Tag = 'text71';
            app.text71.Text = '(km)';
            app.text_slip1_unit = uilabel(app.uipanel5);
            app.text_slip1_unit.Tag = 'text_slip1_unit';
            app.text_slip1_unit.Text = '(m)';
            app.text_slip2_unit = uilabel(app.uipanel5);
            app.text_slip2_unit.Tag = 'text_slip2_unit';
            app.text_slip2_unit.Text = '(m)';
            app.text74 = uilabel(app.uipanel5);
            app.text74.Tag = 'text74';
            app.text74.Text = '(°)';
            app.text75 = uilabel(app.uipanel5);
            app.text75.Tag = 'text75';
            app.text75.Text = '(km)';
            app.text76 = uilabel(app.uipanel5);
            app.text76.Tag = 'text76';
            app.text76.Text = '(km)';
            app.text40 = uilabel(app.uipanel5);
            app.text40.Tag = 'text40';
            app.text40.Text = 'x start';
            app.text41 = uilabel(app.uipanel5);
            app.text41.Tag = 'text41';
            app.text41.Text = 'y start';
            app.text42 = uilabel(app.uipanel5);
            app.text42.Tag = 'text42';
            app.text42.Text = 'x finish';
            app.text43 = uilabel(app.uipanel5);
            app.text43.Tag = 'text43';
            app.text43.Text = 'y finish';
            app.text45 = uilabel(app.uipanel5);
            app.text45.Tag = 'text45';
            app.text45.Text = '#';
            app.text_input_latslip = uilabel(app.uipanel5);
            app.text_input_latslip.Tag = 'text_input_latslip';
            app.text_input_latslip.Text = 'rt.lat slip';
            app.text_input_dipslip = uilabel(app.uipanel5);
            app.text_input_dipslip.Tag = 'text_input_dipslip';
            app.text_input_dipslip.Text = 'dip slip';
            app.text48 = uilabel(app.uipanel5);
            app.text48.Tag = 'text48';
            app.text48.Text = 'dip';
            app.text49 = uilabel(app.uipanel5);
            app.text49.Tag = 'text49';
            app.text49.Text = 'top';
            app.text50 = uilabel(app.uipanel5);
            app.text50.Tag = 'text50';
            app.text50.Text = 'bottom';
            app.text51 = uilabel(app.uipanel5);
            app.text51.Tag = 'text51';
            app.text51.Text = 'Total number of faults';
            app.text67 = uilabel(app.uipanel5);
            app.text67.Tag = 'text67';
            app.text67.Text = 'Kode';
            app.edit_totalnf = uieditfield(app.uipanel5, 'text');
            app.edit_totalnf.ValueChangedFcn = createCallbackFcn(app, @edit_totalnf_Callback, true);
            app.edit_totalnf.Tag = 'edit_totalnf';
            app.edit_elem = uieditfield(app.uipanel5, 'text');
            app.edit_elem.ValueChangedFcn = createCallbackFcn(app, @edit_elem_Callback, true);
            app.edit_elem.Tag = 'edit_elem';
            app.edit_fxstart = uieditfield(app.uipanel5, 'text');
            app.edit_fxstart.ValueChangedFcn = createCallbackFcn(app, @edit_fxstart_Callback, true);
            app.edit_fxstart.Tag = 'edit_fxstart';
            app.edit_fxstart.Value = '-15.00';
            app.edit_fystart = uieditfield(app.uipanel5, 'text');
            app.edit_fystart.ValueChangedFcn = createCallbackFcn(app, @edit_fystart_Callback, true);
            app.edit_fystart.Tag = 'edit_fystart';
            app.edit_fystart.Value = '-17.00';
            app.edit_fxfinish = uieditfield(app.uipanel5, 'text');
            app.edit_fxfinish.ValueChangedFcn = createCallbackFcn(app, @edit_fxfinish_Callback, true);
            app.edit_fxfinish.Tag = 'edit_fxfinish';
            app.edit_fxfinish.Value = '15.20';
            app.edit_fyfinish = uieditfield(app.uipanel5, 'text');
            app.edit_fyfinish.ValueChangedFcn = createCallbackFcn(app, @edit_fyfinish_Callback, true);
            app.edit_fyfinish.Tag = 'edit_fyfinish';
            app.edit_fyfinish.Value = '17.20';
            app.edit_input_kode = uieditfield(app.uipanel5, 'text');
            app.edit_input_kode.ValueChangedFcn = createCallbackFcn(app, @edit_input_kode_Callback, true);
            app.edit_input_kode.Tag = 'edit_input_kode';
            app.edit_laterals = uieditfield(app.uipanel5, 'text');
            app.edit_laterals.ValueChangedFcn = createCallbackFcn(app, @edit_laterals_Callback, true);
            app.edit_laterals.Tag = 'edit_laterals';
            app.edit_laterals.Value = '-3.000';
            app.edit_dips = uieditfield(app.uipanel5, 'text');
            app.edit_dips.ValueChangedFcn = createCallbackFcn(app, @edit_dips_Callback, true);
            app.edit_dips.Tag = 'edit_dips';
            app.edit_dips.Value = '0.000';
            app.edit_dip = uieditfield(app.uipanel5, 'text');
            app.edit_dip.ValueChangedFcn = createCallbackFcn(app, @edit_dip_Callback, true);
            app.edit_dip.Tag = 'edit_dip';
            app.edit_dip.Value = '89.9';
            app.edit_top = uieditfield(app.uipanel5, 'text');
            app.edit_top.ValueChangedFcn = createCallbackFcn(app, @edit_top_Callback, true);
            app.edit_top.Tag = 'edit_top';
            app.edit_top.Value = '5.00';
            app.edit_bottom = uieditfield(app.uipanel5, 'text');
            app.edit_bottom.ValueChangedFcn = createCallbackFcn(app, @edit_bottom_Callback, true);
            app.edit_bottom.Tag = 'edit_bottom';
            app.edit_bottom.Value = '15.00';
            app.uipanel1 = uipanel(app.input_window);
            app.uipanel1.Title = '  Half space parameters  ';
            app.uipanel1.Tag = 'uipanel1';
            app.edit_poisson = uieditfield(app.uipanel1, 'text');
            app.edit_poisson.ValueChangedFcn = createCallbackFcn(app, @edit_poisson_Callback, true);
            app.edit_poisson.Tag = 'edit_poisson';
            app.edit_young = uieditfield(app.uipanel1, 'text');
            app.edit_young.ValueChangedFcn = createCallbackFcn(app, @edit_young_Callback, true);
            app.edit_young.Tag = 'edit_young';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Poisson''s ratio';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Young modulus';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'bar';
            app.text52 = uilabel(app.uipanel1);
            app.text52.Tag = 'text52';
            app.text52.Text = 'Poisson''s ratio';
            app.uipanel2 = uipanel(app.input_window);
            app.uipanel2.Title = '  Regional stress (only used for optimum stress calc.)  ';
            app.uipanel2.Tag = 'uipanel2';
            app.edit_s1or = uieditfield(app.uipanel2, 'text');
            app.edit_s1or.ValueChangedFcn = createCallbackFcn(app, @edit_s1or_Callback, true);
            app.edit_s1or.Tag = 'edit_s1or';
            app.edit_s1or.Value = '19.0';
            app.edit_s1dip = uieditfield(app.uipanel2, 'text');
            app.edit_s1dip.ValueChangedFcn = createCallbackFcn(app, @edit_s1dip_Callback, true);
            app.edit_s1dip.Tag = 'edit_s1dip';
            app.edit_s1dip.Value = '-0.0';
            app.edit_s1in = uieditfield(app.uipanel2, 'text');
            app.edit_s1in.ValueChangedFcn = createCallbackFcn(app, @edit_s1in_Callback, true);
            app.edit_s1in.Tag = 'edit_s1in';
            app.edit_s1in.Value = '100.0';
            app.edit_s1grad = uieditfield(app.uipanel2, 'text');
            app.edit_s1grad.ValueChangedFcn = createCallbackFcn(app, @edit_s1grad_Callback, true);
            app.edit_s1grad.Tag = 'edit_s1grad';
            app.edit_s1grad.Value = '0.0';
            app.edit_s2or = uieditfield(app.uipanel2, 'text');
            app.edit_s2or.ValueChangedFcn = createCallbackFcn(app, @edit_s2or_Callback, true);
            app.edit_s2or.Tag = 'edit_s2or';
            app.edit_s2or.Value = '90.0';
            app.edit_s2dip = uieditfield(app.uipanel2, 'text');
            app.edit_s2dip.ValueChangedFcn = createCallbackFcn(app, @edit_s2dip_Callback, true);
            app.edit_s2dip.Tag = 'edit_s2dip';
            app.edit_s2dip.Value = '90.0';
            app.edit_s2in = uieditfield(app.uipanel2, 'text');
            app.edit_s2in.ValueChangedFcn = createCallbackFcn(app, @edit_s2in_Callback, true);
            app.edit_s2in.Tag = 'edit_s2in';
            app.edit_s2in.Value = '30.0';
            app.edit_s2grad = uieditfield(app.uipanel2, 'text');
            app.edit_s2grad.ValueChangedFcn = createCallbackFcn(app, @edit_s2grad_Callback, true);
            app.edit_s2grad.Tag = 'edit_s2grad';
            app.edit_s2grad.Value = '0.0';
            app.edit_s3or = uieditfield(app.uipanel2, 'text');
            app.edit_s3or.ValueChangedFcn = createCallbackFcn(app, @edit_s3or_Callback, true);
            app.edit_s3or.Tag = 'edit_s3or';
            app.edit_s3or.Value = '109.0';
            app.edit_s3dip = uieditfield(app.uipanel2, 'text');
            app.edit_s3dip.ValueChangedFcn = createCallbackFcn(app, @edit_s3dip_Callback, true);
            app.edit_s3dip.Tag = 'edit_s3dip';
            app.edit_s3dip.Value = '-0.0';
            app.edit_s3in = uieditfield(app.uipanel2, 'text');
            app.edit_s3in.ValueChangedFcn = createCallbackFcn(app, @edit_s3in_Callback, true);
            app.edit_s3in.Tag = 'edit_s3in';
            app.edit_s3in.Value = '0.0';
            app.edit_s3grad = uieditfield(app.uipanel2, 'text');
            app.edit_s3grad.ValueChangedFcn = createCallbackFcn(app, @edit_s3grad_Callback, true);
            app.edit_s3grad.Tag = 'edit_s3grad';
            app.edit_s3grad.Value = '0.0';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'S2';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'S3';
            app.text14 = uilabel(app.uipanel2);
            app.text14.Tag = 'text14';
            app.text14.Text = 'plunge°';
            app.text17 = uilabel(app.uipanel2);
            app.text17.Tag = 'text17';
            app.text17.Text = {'surface'; 'stress'};
            app.text20 = uilabel(app.uipanel2);
            app.text20.Tag = 'text20';
            app.text20.Text = 'bar';
            app.text21 = uilabel(app.uipanel2);
            app.text21.Tag = 'text21';
            app.text21.Text = 'bar';
            app.text22 = uilabel(app.uipanel2);
            app.text22.Tag = 'text22';
            app.text22.Text = 'bar';
            app.text53 = uilabel(app.uipanel2);
            app.text53.Tag = 'text53';
            app.text53.Text = 'azimuth°';
            app.text54 = uilabel(app.uipanel2);
            app.text54.Tag = 'text54';
            app.text54.Text = 'azimuth°';
            app.text57 = uilabel(app.uipanel2);
            app.text57.Tag = 'text57';
            app.text57.Text = {'surface'; 'stress'};
            app.text58 = uilabel(app.uipanel2);
            app.text58.Tag = 'text58';
            app.text58.Text = {'surface'; 'stress'};
            app.text59 = uilabel(app.uipanel2);
            app.text59.Tag = 'text59';
            app.text59.Text = {'vertical'; 'gradient'};
            app.text61 = uilabel(app.uipanel2);
            app.text61.Tag = 'text61';
            app.text61.Text = {'vertical'; 'gradient'};
            app.text62 = uilabel(app.uipanel2);
            app.text62.Tag = 'text62';
            app.text62.Text = 'bar/km';
            app.text63 = uilabel(app.uipanel2);
            app.text63.Tag = 'text63';
            app.text63.Text = 'bar/km';
            app.text64 = uilabel(app.uipanel2);
            app.text64.Tag = 'text64';
            app.text64.Text = 'bar/km';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'S1';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Tag = 'text11';
            app.text11.Text = 'azimuth°';
            app.text77 = uilabel(app.uipanel2);
            app.text77.Tag = 'text77';
            app.text77.Text = 'plunge°';
            app.text78 = uilabel(app.uipanel2);
            app.text78.Tag = 'text78';
            app.text78.Text = 'plunge°';
            app.text61_2 = uilabel(app.uipanel2);
            app.text61_2.Tag = 'text61';
            app.text61_2.Text = {'vertical'; 'gradient'};
            % Show the figure after all components are created
            app.input_window.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = input_window_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.input_window)

                % Execute the startup function
                runStartupFcn(app, @(app)input_window_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.input_window)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.input_window)
        end
    end
end